classdef GNSSR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GNSSRAppUIFigure                matlab.ui.Figure
        MenuSNR                         matlab.ui.container.Menu
        ReflectedSignalsextractionMenu  matlab.ui.container.Menu
        ReflectorHeightcalculationMenu  matlab.ui.container.Menu
        HighresolutionsealevelestimationMenu  matlab.ui.container.Menu
        Menu_Quit                       matlab.ui.container.Menu
        MenuAbout                       matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        Tab_dSNRextraction              matlab.ui.container.Tab
        GridLaydSNRextraction           matlab.ui.container.GridLayout
        Panel_loaddata                  matlab.ui.container.Panel
        NselectButton                   matlab.ui.control.Button
        NTextArea                       matlab.ui.control.TextArea
        NavigationfileLabel             matlab.ui.control.Label
        OselectButton                   matlab.ui.control.Button
        OTextArea                       matlab.ui.control.TextArea
        ObservationfileLabel            matlab.ui.control.Label
        Panel_dSNRoutput                matlab.ui.container.Panel
        OutputfilepathTextArea          matlab.ui.control.TextArea
        OutputfilepathTextAreaLabel     matlab.ui.control.Label
        NfilepathTextArea               matlab.ui.control.TextArea
        NfilepathTextAreaLabel          matlab.ui.control.Label
        OfilepathTextArea               matlab.ui.control.TextArea
        OfilepathTextAreaLabel          matlab.ui.control.Label
        dSNRoutputTextArea              matlab.ui.control.TextArea
        Label_24                        matlab.ui.control.Label
        outputEditField                 matlab.ui.control.EditField
        outpufilepathtButton            matlab.ui.control.Button
        dSNRoutputButton                matlab.ui.control.Button
        Tab_PreProcess                  matlab.ui.container.Tab
        GridLayoutPreProcess            matlab.ui.container.GridLayout
        Panel_RHdataoutput              matlab.ui.container.Panel
        ButtonrawRHshow                 matlab.ui.control.Button
        Button_rhcal                    matlab.ui.control.Button
        rhTextArea                      matlab.ui.control.TextArea
        EditField_Slcdatapath           matlab.ui.control.EditField
        Label_25                        matlab.ui.control.Label
        Button_Slcdataselect            matlab.ui.control.Button
        UIAxesrawRHshow                 matlab.ui.control.UIAxes
        Panel_strategy                  matlab.ui.container.Panel
        Label_35                        matlab.ui.control.Label
        Label_37                        matlab.ui.control.Label
        Label_34                        matlab.ui.control.Label
        Label_36                        matlab.ui.control.Label
        Label_area2azim                 matlab.ui.control.Label
        Label_area2ele                  matlab.ui.control.Label
        RefregionButtonGroup            matlab.ui.container.ButtonGroup
        Button_areaSingle               matlab.ui.control.RadioButton
        Button_areaDouble               matlab.ui.control.RadioButton
        EditField_azimstart2            matlab.ui.control.NumericEditField
        EditField_elestart2             matlab.ui.control.NumericEditField
        EditField_azimstart             matlab.ui.control.NumericEditField
        Label_30                        matlab.ui.control.Label
        EditField_elestart              matlab.ui.control.NumericEditField
        Label_28                        matlab.ui.control.Label
        EditField_azimfinal2            matlab.ui.control.NumericEditField
        EditField_elefinal2             matlab.ui.control.NumericEditField
        EditField_azimfinal             matlab.ui.control.NumericEditField
        EditField_elefinal              matlab.ui.control.NumericEditField
        reflectorheight_maxLabel        matlab.ui.control.Label
        EditField_minrfheight           matlab.ui.control.NumericEditField
        EditField_maxrfheifht           matlab.ui.control.NumericEditField
        reflectorheight_minLabel        matlab.ui.control.Label
        Label_method                    matlab.ui.control.Label
        SNRCheckBox                     matlab.ui.control.CheckBox
        CMCCheckBox                     matlab.ui.control.CheckBox
        Label_system                    matlab.ui.control.Label
        GPSCheckBox                     matlab.ui.control.CheckBox
        GalileoCheckBox                 matlab.ui.control.CheckBox
        GLONASSCheckBox                 matlab.ui.control.CheckBox
        BDSCheckBox                     matlab.ui.control.CheckBox
        Label_parameters                matlab.ui.control.Label
        Tab_WinRH                       matlab.ui.container.Tab
        GridLayoutWinRH                 matlab.ui.container.GridLayout
        Panel_picshow                   matlab.ui.container.Panel
        Buttonpicsave                   matlab.ui.control.Button
        Buttonwinrhshow                 matlab.ui.control.Button
        UIAxesoutdectRH                 matlab.ui.control.UIAxes
        UIAxeswinRH                     matlab.ui.control.UIAxes
        Panel_dataselect                matlab.ui.container.Panel
        EditFieldorder                  matlab.ui.control.EditField
        DropDownorder                   matlab.ui.control.DropDown
        Label_33                        matlab.ui.control.Label
        Label_method_2                  matlab.ui.control.Label
        SNRCheckBox_2                   matlab.ui.control.CheckBox
        CMCCheckBox_2                   matlab.ui.control.CheckBox
        Label_system_2                  matlab.ui.control.Label
        GPSCheckBox_2                   matlab.ui.control.CheckBox
        GalileoCheckBox_2               matlab.ui.control.CheckBox
        GLONASSCheckBox_2               matlab.ui.control.CheckBox
        BDSCheckBox_2                   matlab.ui.control.CheckBox
        EditFieldwinstep                matlab.ui.control.NumericEditField
        winstepLabel                    matlab.ui.control.Label
        EditFieldwinlen                 matlab.ui.control.NumericEditField
        winlengthLabel                  matlab.ui.control.Label
        EditFieldstartdoy               matlab.ui.control.NumericEditField
        EditFieldenddoy                 matlab.ui.control.NumericEditField
        DOYLabel                        matlab.ui.control.Label
        DOYstartLabel                   matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        RHtxtEditField                  matlab.ui.control.EditField
        RHselectButton                  matlab.ui.control.Button
        Button_dataana                  matlab.ui.control.Button
    end


    properties (Access = private)
        Property % Description
        imname;
        dSNRlog;
        RHlog;
    end

    methods (Access = private)

        function logoutput(app,str)
            lognew = sprintf('%s\n',str); 
            app.dSNRlog = horzcat(app.dSNRlog,lognew); 
            app.dSNRoutputTextArea.Value = app.dSNRlog;
        end

        function logrh(app,str2)
            lognew2 = sprintf('%s\n',str2); 
            app.RHlog = horzcat(app.RHlog,lognew2); 
            app.rhTextArea.Value = app.RHlog;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dSNRlog{1} = sprintf('%s\n','—————Notes—————'); 
            app.dSNRoutputTextArea.Value = app.dSNRlog{1};
            app.RHlog{1} = sprintf('%s\n','——————Notes——————'); 
            app.rhTextArea.Value = app.RHlog{1};
            app.EditField_Slcdatapath.Value = 'path of the Slcdata data';
            Position_Screen = get(0,'ScreenSize');
            Position_app = app.GNSSRAppUIFigure.Position;
            Position_app(1) = (Position_Screen(3) - Position_app(3))/2 ;
            Position_app(2) = (Position_Screen(4)- Position_app(4))/2 ;
            app.GNSSRAppUIFigure.Position = Position_app;


        end

        % Button pushed function: OselectButton
        function OselectButtonPushed(app, event)
            global filenameodir2 num_fileo
            %             one by one
            %             global pathnameo
            %             [filenameo,pathnameo] = uigetfile({'*.o';'*.*'},'Browse','MultiSelect','on');
            %             if isequal(filenameo,0) || isequal(pathnameo,0)  
            %                 errordlg("/No data","Warn");
            %                 return;
            %             end
            filenameodir = uigetdir;
            if isequal(filenameodir,0)  
                errordlg("CANCELED","Warn");
            else
                fileo = dir(filenameodir);
                fileoname = {fileo.name}';
                filenameodir2 = fileo.folder;
                num_fileo = size(fileoname,1);
                fileoname(1:2, :) = [];
                app.OTextArea.Value = fileoname;
                app.OfilepathTextArea.Value = filenameodir2;
            end

            %           app.OfilepathLabel.Text = ['O filepath : ' filenameodir] ;

            %             addpath(genpath(pathnameo));
            %             if ischar(filenameo)
            %                 app.OfilepathEditField.Value = filenameo ;
            %             else
            %                 app.OfilepathEditField.Value = 'filenameo';
            %             end

            %           app.OfilepathEditField.Value = fullfile(pathnameo,filenameo); 
            %             filenameochar = char(filenameo);
            %             namelist = strcat(pathnameo,filenameochar);
            %             app.OfilepathEditField.Value = string(namelist);
        end

        % Callback function
        function outpufilepathtButtonPushed(app, event)
            outputpath = uigetdir(path,'Outputpath');
            app.outputEditField.Value = outputpath;
        end

        % Menu selected function: MenuAbout
        function MenuAboutSelected(app, event)
            msgbox({'Reference: ZHANG Z,GUO F,ZHANG X,et al.First Result of GNSS-R-based Sea Level Retrieval with CMC and its Combination with the SNR Method[J].GPS Solutions,2022,26(1):20.' ...
                },"About","help");
        end

        % Button pushed function: NselectButton
        function NselectButtonPushed(app, event)
            global filenamendir2 num_filen
            filenamendir = uigetdir;
            if isequal(filenamendir,0)
                errordlg("CANCELED","Warn");
            else
                filen = dir(filenamendir);
                filenname = {filen.name}';
                filenamendir2 = filen.folder;
                num_filen = size(filenname,1);
                filenname(1:2, :) = [];
                app.NTextArea.Value = filenname;
                app.NfilepathTextArea.Value = filenamendir2;
            end
        end

        % Button pushed function: outpufilepathtButton
        function outpufilepathtButtonPushed2(app, event)
            global outputdir2
            outputdir = uigetdir;
            if isequal(outputdir,0)  
                errordlg("CANCELED","Warn");
            else
                fileoutput = dir(outputdir);
                outputdir2 = fileoutput.folder;
                app.OutputfilepathTextArea.Value = outputdir2;
                app.outputEditField.Value = outputdir2;
            end

        end

        % Button pushed function: dSNRoutputButton
        function dSNRoutputButtonPushed(app, event)
            global filenameodir2 filenamendir2 outputdir2 num_fileo
            if isempty(filenameodir2)
                errordlg("No observation file","Warn");
                return;
            else
                if isempty(filenamendir2)
                    errordlg("No navigation file","Warn");
                    return;
                else
                    if isempty(outputdir2)
                        errordlg("No output filepath","Warn");
                        return;
                    end
                end
            end
            %app.dSNRoutputTextArea.Value = num2str([filenameodir2 filenamendir2  outputdir2]);  
            filenameodir3 =  [ filenameodir2 '\'];
            filenamendir3 =  [ filenamendir2 '\'];
            outputdir3 =  [ outputdir2 '\'];
            outputdir4 = replace(outputdir3,'\','/');
            num_fileo2 = num_fileo - 2;
            string = num2str(num_fileo2);
            string2 = [string ' ' 'files choosed'];
            logoutput(app,string2);drawnow;
            creNameList(filenameodir3,filenamendir3,outputdir3)  
            logoutput(app,'Please verify the name list files');drawnow;
            d = uiprogressdlg(app.GNSSRAppUIFigure,'Title','Computing','Message','Please Wait','Indeterminate',"on");
            mymex(outputdir4);
            logoutput(app,'Slcdata data has been output');drawnow;
            close(d);


        end

        % Close request function: GNSSRAppUIFigure
        function GNSSRAppUIFigureCloseRequest(app, event)
            clearvars -global -except app
            delete(app)
        end

        % Button pushed function: Button_Slcdataselect
        function Button_SlcdataselectPushed(app, event)
            global  slcdata2
            slcdatadir = uigetdir;
            if isequal(slcdatadir,0) 
                errordlg("CANCELED","Warn");
            else
                slcdata = dir(slcdatadir);
                slcdata2 = slcdata.folder;
                app.EditField_Slcdatapath.Value = slcdata2;
            end

        end

        % Button pushed function: Button_rhcal
        function Button_rhcalPushed(app, event)
            global  slcdata2
            selectedButton = app.RefregionButtonGroup.SelectedObject;

            if strcmp(selectedButton.Text,'one-part')
                areatag = 1;
                elestart = app.EditField_elestart.Value;     elefinal = app.EditField_elefinal.Value;
                azimstart = app.EditField_azimstart.Value;   azimfinal = app.EditField_azimfinal.Value;

                if elestart > elefinal
                    errordlg("ele-start should be less than ele-end","Warn");
                    return
                else
                    if azimstart > azimfinal
                        errordlg("azim-start should be less than azim-end","Warn");
                        return
                    end
                end

                elemask = [app.EditField_elestart.Value , app.EditField_elefinal.Value];
                azimmask= [app.EditField_azimstart.Value , app.EditField_azimfinal.Value];
            else
                areatag = 2;
                elestart = app.EditField_elestart.Value;     elefinal = app.EditField_elefinal.Value;
                azimstart = app.EditField_azimstart.Value;   azimfinal = app.EditField_azimfinal.Value;
                elestartarea= app.EditField_elestart2.Value;     elefinalarea = app.EditField_elefinal2.Value;
                azimstartarea = app.EditField_azimstart2.Value;   azimfinalarea = app.EditField_azimfinal2.Value;
                if elestart > elefinal
                    errordlg("ele-start should be less than ele-end","Warn");
                    return
                else
                    if elestartarea > elefinalarea
                        errordlg("ele-start should be less than ele-end","Warn");
                        return
                    else
                        if azimstart > azimfinal
                            errordlg("azim-start should be less than azim-end","Warn");
                            return
                        else
                            if azimstartarea > azimfinalarea
                                errordlg("azim-start should be less than azim-end","Warn");
                                return
                            end
                        end
                    end
                end

                if azimstart == azimfinalarea
                    errordlg("The two region should be independent","Warn");
                    return
                end

                elemask = [app.EditField_elestart.Value , app.EditField_elefinal.Value ;  app.EditField_elestart2.Value , app.EditField_elefinal2.Value];
                azimmask= [app.EditField_azimstart.Value , app.EditField_azimfinal.Value ; app.EditField_azimstart2.Value , app.EditField_azimfinal2.Value];
            end

            %             elemask = [app.EditField_elestart.Value , app.EditField_elefinal.Value];
            %             azimmask= [app.EditField_azimstart.Value , app.EditField_azimfinal.Value];
            %             elestart = app.EditField_elestart.Value;     elefinal = app.EditField_elefinal.Value;
            %             azimstart = app.EditField_azimstart.Value;   azimfinal = app.EditField_azimfinal.Value


            rhmin = app.EditField_minrfheight.Value;     rhmax = app.EditField_maxrfheifht.Value;

            if rhmin > rhmax
                errordlg("rh_min should be less than rh_max","Warn");
                return
            end


            sysG = app.GPSCheckBox.Value;
            sysR = app.GLONASSCheckBox.Value;
            sysE = app.GalileoCheckBox.Value;
            sysC = app.BDSCheckBox.Value;

            typeSNR = app.SNRCheckBox.Value;
            typeCMC = app.CMCCheckBox.Value;

            if sysG == false && sysR == false && sysE == false && sysC == false
                errordlg("At least one Constellation","Warn");
                return
            else
                if typeSNR == false && typeCMC == false
                    errordlg("At least one Method","Warn");
                    return
                end
            end

            if sysG == true; sysG1 = 'G';  else;  sysG1 = '';  end
            if sysR == true; sysR1 = 'R';  else;  sysR1 = '';  end
            if sysE == true; sysE1 = 'E';  else;  sysE1 = '';  end
            if sysC == true; sysC1 = 'C';  else;  sysC1 = '';  end
            systype = [sysG1 sysR1 sysE1 sysC1];

            if typeSNR == true; typeSNR1 = 'SNR';  else;  typeSNR1 = '';  end
            if typeCMC == true; typeCMC1 = 'CMC';  else;  typeCMC1 = '';  end
            methodtype = [typeSNR1 typeCMC1];
            methodtype2 = [typeSNR1 ' ' typeCMC1];

            if isempty(slcdata2) 
                errordlg("No Slcdata file","Warn");
                return
            end

            string1 = "Parameter selected";

            if areatag == 1
                elestart2 = num2str(elestart);    elefinal2 = num2str(elefinal);
                azimstart2 = num2str(azimstart);  azimfinal2 = num2str(azimfinal);
                string2 = ['ele:' elestart2 '~' elefinal2 '°' ];
                string3 = ['azim:' azimstart2 '~' azimfinal2 '°' ];
            else
                elestart2 = num2str(elestart);    elefinal2 = num2str(elefinal);
                azimstart2 = num2str(azimstart);  azimfinal2 = num2str(azimfinal);
                elestartarea2 = num2str(elestartarea);    elefinalarea2 = num2str(elefinalarea);
                azimstartarea2 = num2str(azimstartarea);  azimfinalarea2 = num2str(azimfinalarea);
                string2 = ['ele:' elestart2 '~' elefinal2 '°'  ' ; '  elestartarea2 '~' elefinalarea2 '°' ];
                string3 = ['azim:' azimstart2 '~' azimfinal2 '°'  ' ; ' azimstartarea2 '~' azimfinalarea2 '°' ];
            end

            rhmin2 = num2str(rhmin);          rhmax2 = num2str(rhmax);
            string4 = ['rh:' rhmin2 '~' rhmax2 'm' ];
            string5 = ['system:' systype '  ' 'method:' methodtype2];
            string6 = ['Slcdata filepath:' slcdata2];
            string7 = horzcat(string1,string2,string3,string4,string5,string6);
            logrh(app,string7);drawnow;
            slcdata3 = [slcdata2 '\'];
            d2 = uiprogressdlg(app.GNSSRAppUIFigure,'Title','Computing RH','Message','Please Wait','Indeterminate',"on");
            rhCal(systype,slcdata3,elemask,azimmask,areatag,rhmin,rhmax,methodtype);
            close(d2);
            logrh(app,'Please verify the RH files');drawnow;
        end

        % Selection changed function: RefregionButtonGroup
        function RefregionButtonGroupSelectionChanged(app, event)
            %             selectedButton = app.RefregionButtonGroup.SelectedObject;
            if app.Button_areaSingle.Value
                app.EditField_elestart2.Enable = 'off';  app.EditField_elefinal2.Enable = 'off';
                app.EditField_azimstart2.Enable = 'off'; app.EditField_azimfinal2.Enable = 'off';
                app.Label_area2ele.Enable = 'off';       app.Label_area2azim.Enable = 'off';
                app.Label_35.Enable = 'off';             app.Label_36.Enable = 'off';
                
            else
                app.EditField_elestart2.Enable = 'on';  app.EditField_elefinal2.Enable = 'on';
                app.EditField_azimstart2.Enable = 'on'; app.EditField_azimfinal2.Enable = 'on';
                app.Label_area2ele.Enable = 'on';       app.Label_area2azim.Enable = 'on';
                app.Label_35.Enable = 'on';             app.Label_36.Enable = 'on';
                %                 app.TextArea.Value = string(app.Button_areaSingle.Value);
                %                 app.EditField_3.Value = class(app.Button_areaSingle.Value);
                %                 app.TextArea2.Value = selectedButton.Text;

            end
        end

        % Button pushed function: RHselectButton
        function RHselectButtonPushed(app, event)
            global  rhpath2
            rhdir = uigetdir;
            if isequal(rhdir,0) 
                errordlg("CANCELED","Warn");
            else
                rhpath = dir(rhdir);
                rhpath2 = rhpath.folder;
                app.RHtxtEditField.Value = rhpath2;
            end
        end

        % Button pushed function: Button_dataana
        function Button_dataanaPushed(app, event)
            global rhpath2

            if isempty(rhpath2)  
                errordlg("CANCELED","Warn");
                return
            end

            startdoy = app.EditFieldstartdoy.Value;  enddoy = app.EditFieldenddoy.Value;
            winlen = app.EditFieldwinlen.Value;      winstep = app.EditFieldwinstep.Value;

            if startdoy > enddoy
                errordlg("DOY-start should be less than DOY-end","Warn");
                return
            end

            if winlen == 0
                errordlg("win-length is non-zero","Warn");
                return
            else
                if winstep == 0
                    errordlg("win-step is non-zero","Warn");
                    return
                end
            end

            rhsysG = app.GPSCheckBox_2.Value;
            rhsysR = app.GLONASSCheckBox_2.Value;
            rhsysE = app.GalileoCheckBox_2.Value;
            rhsysC = app.BDSCheckBox_2.Value;

            rhtypeSNR = app.SNRCheckBox_2.Value;
            rhtypeCMC = app.CMCCheckBox_2.Value;

            if rhsysG == false && rhsysR == false && rhsysE == false && rhsysC == false
                errordlg("At least one Constellation","Warn");
                return
            else
                if rhtypeSNR == false && rhtypeCMC == false
                    errordlg("At least one Method","Warn");
                    return
                end
            end

            if rhsysG == true; rhsysG1 = 'G';  else;  rhsysG1 = '';  end
            if rhsysR == true; rhsysR1 = 'R';  else;  rhsysR1 = '';  end
            if rhsysE == true; rhsysE1 = 'E';  else;  rhsysE1 = '';  end
            if rhsysC == true; rhsysC1 = 'C';  else;  rhsysC1 = '';  end
            rhsystype = [rhsysG1 rhsysR1 rhsysE1 rhsysC1];

            if rhtypeSNR == true; rhtypeSNR1 = 'SNR';  else;  rhtypeSNR1 = '';  end
            if rhtypeCMC == true; rhtypeCMC1 = 'CMC';  else;  rhtypeCMC1 = '';  end
            rhmethodtype = [rhtypeSNR1 rhtypeCMC1];

            if app.DropDownorder.Value == "2nd"
                order = 3;
            else
                order = 2;
            end

            d3 = uiprogressdlg(app.GNSSRAppUIFigure,'Title','Computing winRH','Message','Please Wait','Indeterminate',"on");
            dataAnalysis(rhsystype,rhmethodtype,startdoy,enddoy,winstep,winlen,rhpath2,order);
            close(d3)

            msgbox('outlier detection and dynamic correction have been performed','Finnish','help');


        end

        % Value changed function: DropDownorder
        function DropDownorderValueChanged(app, event)
            value = app.DropDownorder.Value;

            if value == "2nd"
                app.EditFieldorder.Value = "velocity and acceleration";
            else
                app.EditFieldorder.Value = "velocity only";
            end

        end

        % Button pushed function: Buttonwinrhshow
        function ButtonwinrhshowPushed(app, event)
            winrhpath = uigetdir;
            if isequal(winrhpath,0) 
                errordlg("CANCELED","Warn");
                return
            else
                winrhpath2 = winrhpath;
            end

            outdectrhname =  [winrhpath2 '\' 'outdectRH.txt'];
            winRHname = [winrhpath2 '\' 'winRH.txt'];

            if ~exist(outdectrhname,'file')
                msgbox('No RH file','Wrong path','error')
                return
            else
                if ~exist(winRHname,'file')
                    msgbox('No RH file','Wrong path','error')
                    return
                end
            end

            [a,b] =textread(outdectrhname,'%f%f','headerlines',1);
            [c,d] =textread(winRHname,'%f%f','headerlines',1);


            timemin = floor(min(a)); timemax = ceil(max(a));
            timedif = timemax - timemin;
            time1 = floor(min(a));
            if timedif > 1
                time2 = time1+2;
            else
                time2 = time1+1;
            end

            timeshow1 = find(a > time1 & a <time2);
            t1 = a(timeshow1,:);
            rh1 = b(timeshow1,:);

            timeshow2 = find(c > time1 & c < time2 );
            t2 = c(timeshow2,:);
            rh2 = d(timeshow2,:);

            plot(app.UIAxesoutdectRH,t1,rh1,'r.');
            app.UIAxesoutdectRH.XGrid = 'on';
            app.UIAxesoutdectRH.YGrid = 'on';
            xlim(app.UIAxesoutdectRH,[time1,time2]);
            plot(app.UIAxeswinRH,t2,rh2,'b.');
            app.UIAxeswinRH.XGrid = 'on';
            app.UIAxeswinRH.YGrid = 'on';

        end

        % Button pushed function: Buttonpicsave
        function ButtonpicsavePushed(app, event)
            %%选择格式
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf';'*.eps'};
            [filenamepic,filepathpic] = uiputfile(filter,'Save Pictures');
            if isequal(filenamepic,0) || isequal(filepathpic,0)
                errordlg("No filepath and filename","Warn")
                return
            end
            filenamepic1 = ['outdectRH' filenamepic ]; filenamepic2 = ['winRH' filenamepic ];
            exportgraphics(app.UIAxesoutdectRH,fullfile(filepathpic,filenamepic1),'Resolution',600);
            exportgraphics(app.UIAxeswinRH,fullfile(filepathpic,filenamepic2),'Resolution',600);
        end

        % Button pushed function: ButtonrawRHshow
        function ButtonrawRHshowPushed(app, event)
            [f,p] = uigetfile('*.txt','RHtxt Selcet');
            if isequal(f , 0)
                errordlg("No Data","Warn")
                return
            end
            rawrhdir = fullfile(p,f);

            fileID =fopen(rawrhdir);
            rawrhfile = textscan(fileID,'%*f %*f %f %*f %*s %*f %*f %*f %f %*f %*f %*f %*f ','HeaderLines',2);
            fclose(fileID);

            rawrh = rawrhfile{1};
            utc = rawrhfile{2};

            meanh = mean(rawrh);
            rawrh2 = rawrh - meanh;
            plot(app.UIAxesrawRHshow,utc,rawrh2,'g.');
            app.UIAxeswinRH.XGrid = 'on';
            app.UIAxeswinRH.YGrid = 'on';

        end

        % Menu selected function: ReflectedSignalsextractionMenu
        function ReflectedSignalsextractionMenuSelected(app, event)
            app.TabGroup.SelectedTab = app.Tab_dSNRextraction;
        end

        % Menu selected function: ReflectorHeightcalculationMenu
        function ReflectorHeightcalculationMenuSelected(app, event)
            app.TabGroup.SelectedTab = app.Tab_PreProcess;
        end

        % Menu selected function: 
        % HighresolutionsealevelestimationMenu
        function HighresolutionsealevelestimationMenuSelected(app, event)
            app.TabGroup.SelectedTab = app.Tab_WinRH;
        end

        % Callback function
        function Menu_5Selected(app, event)
            app.TabGroup.SelectedTab = app.Tab_Frezone;
        end

        % Menu selected function: Menu_Quit
        function Menu_QuitSelected(app, event)
            choice = questdlg("Quit?","Quit","Yes","No","No");
            switch choice
                case "Yes"
                    delete(app.GNSSRAppUIFigure);
                    return;
                case "No"
                    return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GNSSRAppUIFigure = uifigure('Visible', 'off');
            app.GNSSRAppUIFigure.Name = 'GNSSR-App';
            app.GNSSRAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @GNSSRAppUIFigureCloseRequest, true);
            app.MenuSNR = uimenu(app.GNSSRAppUIFigure);
            app.MenuSNR.Text = 'Menu';
            app.ReflectedSignalsextractionMenu = uimenu(app.MenuSNR);
            app.ReflectedSignalsextractionMenu.MenuSelectedFcn = createCallbackFcn(app, @ReflectedSignalsextractionMenuSelected, true);
            app.ReflectedSignalsextractionMenu.Text = 'Reflected Signals extraction';
            app.ReflectorHeightcalculationMenu = uimenu(app.MenuSNR);
            app.ReflectorHeightcalculationMenu.MenuSelectedFcn = createCallbackFcn(app, @ReflectorHeightcalculationMenuSelected, true);
            app.ReflectorHeightcalculationMenu.Text = 'Reflector Height calculation';
            app.HighresolutionsealevelestimationMenu = uimenu(app.MenuSNR);
            app.HighresolutionsealevelestimationMenu.MenuSelectedFcn = createCallbackFcn(app, @HighresolutionsealevelestimationMenuSelected, true);
            app.HighresolutionsealevelestimationMenu.Text = 'High-resolution sea level estimation';
            app.Menu_Quit = uimenu(app.MenuSNR);
            app.Menu_Quit.MenuSelectedFcn = createCallbackFcn(app, @Menu_QuitSelected, true);
            app.Menu_Quit.Text = 'Quit';
            app.MenuAbout = uimenu(app.GNSSRAppUIFigure);
            app.MenuAbout.MenuSelectedFcn = createCallbackFcn(app, @MenuAboutSelected, true);
            app.MenuAbout.Text = 'About';
            app.GridLayout = uigridlayout(app.GNSSRAppUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab_dSNRextraction = uitab(app.TabGroup);
            app.Tab_dSNRextraction.Title = 'Reflected Signals Extraction';
            app.GridLaydSNRextraction = uigridlayout(app.Tab_dSNRextraction);
            app.Panel_dSNRoutput = uipanel(app.GridLaydSNRextraction);
            app.Panel_dSNRoutput.Title = 'File_Output';
            app.dSNRoutputButton = uibutton(app.Panel_dSNRoutput, 'push');
            app.dSNRoutputButton.ButtonPushedFcn = createCallbackFcn(app, @dSNRoutputButtonPushed, true);
            app.dSNRoutputButton.Icon = 'GUIicon3.png';
            app.dSNRoutputButton.Text = 'Output';
            app.outpufilepathtButton = uibutton(app.Panel_dSNRoutput, 'push');
            app.outpufilepathtButton.ButtonPushedFcn = createCallbackFcn(app, @outpufilepathtButtonPushed2, true);
            app.outpufilepathtButton.Text = 'Browse';
            app.outputEditField = uieditfield(app.Panel_dSNRoutput, 'text');
            app.outputEditField.Editable = 'off';
            app.Label_24 = uilabel(app.Panel_dSNRoutput);
            app.Label_24.Text = 'Output path';
            app.dSNRoutputTextArea = uitextarea(app.Panel_dSNRoutput);
            app.dSNRoutputTextArea.Editable = 'off';
            app.OfilepathTextAreaLabel = uilabel(app.Panel_dSNRoutput);
            app.OfilepathTextAreaLabel.Text = 'O filepath : ';
            app.OfilepathTextArea = uitextarea(app.Panel_dSNRoutput);
            app.OfilepathTextArea.Editable = 'off';
            app.NfilepathTextAreaLabel = uilabel(app.Panel_dSNRoutput);
            app.NfilepathTextAreaLabel.Text = 'N filepath : ';
            app.NfilepathTextArea = uitextarea(app.Panel_dSNRoutput);
            app.NfilepathTextArea.Editable = 'off';
            app.OutputfilepathTextAreaLabel = uilabel(app.Panel_dSNRoutput);
            app.OutputfilepathTextAreaLabel.Text = 'Output filepath : ';
            app.OutputfilepathTextArea = uitextarea(app.Panel_dSNRoutput);
            app.OutputfilepathTextArea.Editable = 'off';
            app.Panel_loaddata = uipanel(app.GridLaydSNRextraction);
            app.Panel_loaddata.Title = 'Data_Read';
            app.ObservationfileLabel = uilabel(app.Panel_loaddata);
            app.ObservationfileLabel.Text = {'Observation'; 'file'};
            app.OTextArea = uitextarea(app.Panel_loaddata);
            app.OTextArea.Editable = 'off';
            app.OselectButton = uibutton(app.Panel_loaddata, 'push');
            app.OselectButton.ButtonPushedFcn = createCallbackFcn(app, @OselectButtonPushed, true);
            app.OselectButton.Icon = 'GUIicon1.png';
            app.OselectButton.Text = 'Browse';
            app.NavigationfileLabel = uilabel(app.Panel_loaddata);
            app.NavigationfileLabel.Text = {'Navigation'; 'file'};
            app.NTextArea = uitextarea(app.Panel_loaddata);
            app.NTextArea.Editable = 'off';
            app.NselectButton = uibutton(app.Panel_loaddata, 'push');
            app.NselectButton.ButtonPushedFcn = createCallbackFcn(app, @NselectButtonPushed, true);
            app.NselectButton.Icon = 'GUIicon2.png';
            app.NselectButton.Text = 'Browse';
            app.Tab_PreProcess = uitab(app.TabGroup);
            app.Tab_PreProcess.Title = 'Reflector Height Calculation';
            app.GridLayoutPreProcess = uigridlayout(app.Tab_PreProcess);
            app.Panel_strategy = uipanel(app.GridLayoutPreProcess);
            app.Panel_strategy.Title = 'Para_Input';
            app.Label_parameters = uilabel(app.Panel_strategy);
            app.Label_parameters.Text = 'Parameters';
            app.BDSCheckBox = uicheckbox(app.Panel_strategy);
            app.BDSCheckBox.Text = 'BDS';
            app.BDSCheckBox.Value = true;
            app.GLONASSCheckBox = uicheckbox(app.Panel_strategy);
            app.GLONASSCheckBox.Text = 'GLONASS';
            app.GLONASSCheckBox.Value = true;
            app.GalileoCheckBox = uicheckbox(app.Panel_strategy);
            app.GalileoCheckBox.Text = 'Galileo';
            app.GalileoCheckBox.Value = true;
            app.GPSCheckBox = uicheckbox(app.Panel_strategy);
            app.GPSCheckBox.Text = 'GPS';
            app.GPSCheckBox.Value = true;
            app.Label_system = uilabel(app.Panel_strategy);
            app.Label_system.Text = 'Constellations';
            app.CMCCheckBox = uicheckbox(app.Panel_strategy);
            app.CMCCheckBox.Text = 'CMC';
            app.SNRCheckBox = uicheckbox(app.Panel_strategy);
            app.SNRCheckBox.Text = 'SNR';
            app.SNRCheckBox.Value = true;
            app.Label_method = uilabel(app.Panel_strategy);
            app.Label_method.Text = 'Methods';
            app.reflectorheight_minLabel = uilabel(app.Panel_strategy);
            app.reflectorheight_minLabel.Text = 'reflector height_min';
            app.EditField_maxrfheifht = uieditfield(app.Panel_strategy, 'numeric');
            app.EditField_maxrfheifht.Limits = [0 100];
            app.EditField_maxrfheifht.Tooltip = {'0-100(m)'};
            app.EditField_minrfheight = uieditfield(app.Panel_strategy, 'numeric');
            app.EditField_minrfheight.Limits = [0 Inf];
            app.EditField_minrfheight.Tooltip = {'0-100(m)'};
            app.reflectorheight_maxLabel = uilabel(app.Panel_strategy);
            app.reflectorheight_maxLabel.Text = 'reflector height_max';
            app.EditField_elefinal = uieditfield(app.Panel_strategy, 'numeric');
            app.EditField_elefinal.Limits = [0 90];
            app.EditField_elefinal.Tooltip = {'0-90(°)'};
            app.EditField_elefinal.Value = 90;
            app.EditField_azimfinal = uieditfield(app.Panel_strategy, 'numeric');
            app.EditField_azimfinal.Limits = [0 360];
            app.EditField_azimfinal.Tag = '0-360(°)';
            app.EditField_azimfinal.Tooltip = {'0-360(°)'};
            app.EditField_azimfinal.Value = 360;
            app.EditField_elefinal2 = uieditfield(app.Panel_strategy, 'numeric');
            app.EditField_elefinal2.Limits = [0 90];
            app.EditField_elefinal2.Enable = 'off';
            app.EditField_elefinal2.Tooltip = {'0-90(°)'};
            app.EditField_elefinal2.Value = 90;
            app.EditField_azimfinal2 = uieditfield(app.Panel_strategy, 'numeric');
            app.EditField_azimfinal2.Limits = [0 360];
            app.EditField_azimfinal2.Tag = '0-360(°)';
            app.EditField_azimfinal2.Enable = 'off';
            app.EditField_azimfinal2.Tooltip = {'0-360(°)'};
            app.EditField_azimfinal2.Value = 360;
            app.Label_28 = uilabel(app.Panel_strategy);
            app.Label_28.Text = 'ele mask';
            app.EditField_elestart = uieditfield(app.Panel_strategy, 'numeric');
            app.EditField_elestart.Limits = [0 90];
            app.EditField_elestart.Tooltip = {'0-90(°)'};
            app.Label_30 = uilabel(app.Panel_strategy);
            app.Label_30.Text = 'azim mask';
            app.EditField_azimstart = uieditfield(app.Panel_strategy, 'numeric');
            app.EditField_azimstart.Limits = [0 360];
            app.EditField_azimstart.Tag = '0-90(°)';
            app.EditField_azimstart.Tooltip = {'0-360(°)'};
            app.EditField_elestart2 = uieditfield(app.Panel_strategy, 'numeric');
            app.EditField_elestart2.Limits = [0 90];
            app.EditField_elestart2.Enable = 'off';
            app.EditField_elestart2.Tooltip = {'0-90(°)'};
            app.EditField_azimstart2 = uieditfield(app.Panel_strategy, 'numeric');
            app.EditField_azimstart2.Limits = [0 360];
            app.EditField_azimstart2.Tag = '0-90(°)';
            app.EditField_azimstart2.Enable = 'off';
            app.EditField_azimstart2.Tooltip = {'0-360(°)'};
            app.RefregionButtonGroup = uibuttongroup(app.Panel_strategy);
            app.RefregionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RefregionButtonGroupSelectionChanged, true);
            app.RefregionButtonGroup.Title = 'Ref-region';
            app.Button_areaDouble = uiradiobutton(app.RefregionButtonGroup);
            app.Button_areaDouble.Text = 'two-part';
            app.Button_areaSingle = uiradiobutton(app.RefregionButtonGroup);
            app.Button_areaSingle.Text = 'one-part';
            app.Button_areaSingle.Value = true;
            app.Label_area2ele = uilabel(app.Panel_strategy);
            app.Label_area2ele.Enable = 'off';
            app.Label_area2ele.Text = 'ele mask2';
            app.Label_area2azim = uilabel(app.Panel_strategy);
            app.Label_area2azim.Enable = 'off';
            app.Label_area2azim.Text = 'azim mask2';
            app.Label_36 = uilabel(app.Panel_strategy);
            app.Label_36.Enable = 'off';
            app.Label_36.Text = '~';
            app.Label_34 = uilabel(app.Panel_strategy);
            app.Label_34.Text = '~';
            app.Label_37 = uilabel(app.Panel_strategy);
            app.Label_37.Text = '~';
            app.Label_35 = uilabel(app.Panel_strategy);
            app.Label_35.Enable = 'off';
            app.Label_35.Text = '~';
            app.Panel_RHdataoutput = uipanel(app.GridLayoutPreProcess);
            app.Panel_RHdataoutput.Title = 'RH_Output';
            app.UIAxesrawRHshow = uiaxes(app.Panel_RHdataoutput);
            xlabel(app.UIAxesrawRHshow, 'UTC')
            ylabel(app.UIAxesrawRHshow, 'rawRH')
            zlabel(app.UIAxesrawRHshow, 'Z')
            app.Button_Slcdataselect = uibutton(app.Panel_RHdataoutput, 'push');
            app.Button_Slcdataselect.ButtonPushedFcn = createCallbackFcn(app, @Button_SlcdataselectPushed, true);
            app.Button_Slcdataselect.Text = 'Browse';
            app.Label_25 = uilabel(app.Panel_RHdataoutput);
            app.Label_25.Text = 'filepath';
            app.EditField_Slcdatapath = uieditfield(app.Panel_RHdataoutput, 'text');
            app.rhTextArea = uitextarea(app.Panel_RHdataoutput);
            app.Button_rhcal = uibutton(app.Panel_RHdataoutput, 'push');
            app.Button_rhcal.ButtonPushedFcn = createCallbackFcn(app, @Button_rhcalPushed, true);
            app.Button_rhcal.Icon = 'GUIicon4.png';
            app.Button_rhcal.Text = 'Calculate';
            app.ButtonrawRHshow = uibutton(app.Panel_RHdataoutput, 'push');
            app.ButtonrawRHshow.ButtonPushedFcn = createCallbackFcn(app, @ButtonrawRHshowPushed, true);
            app.ButtonrawRHshow.Text = 'Show';
            app.Tab_WinRH = uitab(app.TabGroup);
            app.Tab_WinRH.Title = 'High-resolution Sea Level Estimation';
            app.GridLayoutWinRH = uigridlayout(app.Tab_WinRH);
            app.Panel_dataselect = uipanel(app.GridLayoutWinRH);
            app.Panel_dataselect.Title = 'Data_Input';
            app.Button_dataana = uibutton(app.Panel_dataselect, 'push');
            app.Button_dataana.ButtonPushedFcn = createCallbackFcn(app, @Button_dataanaPushed, true);
            app.Button_dataana.Icon = 'GUIicon5.png';
            app.Button_dataana.Text = 'Output';
            app.RHselectButton = uibutton(app.Panel_dataselect, 'push');
            app.RHselectButton.ButtonPushedFcn = createCallbackFcn(app, @RHselectButtonPushed, true);
            app.RHselectButton.Text = 'Browse';
            app.RHtxtEditField = uieditfield(app.Panel_dataselect, 'text');
            app.RHtxtEditField.Editable = 'off';
            app.Label_17 = uilabel(app.Panel_dataselect);
            app.Label_17.Text = 'filepath';
            app.DOYstartLabel = uilabel(app.Panel_dataselect);
            app.DOYstartLabel.Text = 'DOY-start';
            app.DOYLabel = uilabel(app.Panel_dataselect);
            app.DOYLabel.Text = 'DOY-end';
            app.EditFieldenddoy = uieditfield(app.Panel_dataselect, 'numeric');
            app.EditFieldenddoy.Limits = [1 366];
            app.EditFieldenddoy.Tooltip = {'1-366'};
            app.EditFieldenddoy.Value = 366;
            app.EditFieldstartdoy = uieditfield(app.Panel_dataselect, 'numeric');
            app.EditFieldstartdoy.Limits = [1 366];
            app.EditFieldstartdoy.Tooltip = {'1-366'};
            app.EditFieldstartdoy.Value = 1;
            app.winlengthLabel = uilabel(app.Panel_dataselect);
            app.winlengthLabel.Text = 'win-length';
            app.EditFieldwinlen = uieditfield(app.Panel_dataselect, 'numeric');
            app.EditFieldwinlen.Limits = [0 24];
            app.EditFieldwinlen.Tooltip = {'h'};
            app.EditFieldwinlen.Value = 4;
            app.winstepLabel = uilabel(app.Panel_dataselect);
            app.winstepLabel.Text = 'win-step';
            app.EditFieldwinstep = uieditfield(app.Panel_dataselect, 'numeric');
            app.EditFieldwinstep.Limits = [0 180];
            app.EditFieldwinstep.Tooltip = {'min'};
            app.EditFieldwinstep.Value = 10;
            app.BDSCheckBox_2 = uicheckbox(app.Panel_dataselect);
            app.BDSCheckBox_2.Text = 'BDS';
            app.BDSCheckBox_2.Value = true;
            app.GLONASSCheckBox_2 = uicheckbox(app.Panel_dataselect);
            app.GLONASSCheckBox_2.Text = 'GLONASS';
            app.GLONASSCheckBox_2.Value = true;
            app.GalileoCheckBox_2 = uicheckbox(app.Panel_dataselect);
            app.GalileoCheckBox_2.Text = 'Galileo';
            app.GalileoCheckBox_2.Value = true;
            app.GPSCheckBox_2 = uicheckbox(app.Panel_dataselect);
            app.GPSCheckBox_2.Text = 'GPS';
            app.GPSCheckBox_2.Value = true;
            app.Label_system_2 = uilabel(app.Panel_dataselect);
            app.Label_system_2.Text = 'Constellations';
            app.CMCCheckBox_2 = uicheckbox(app.Panel_dataselect);
            app.CMCCheckBox_2.Text = 'CMC';
            app.SNRCheckBox_2 = uicheckbox(app.Panel_dataselect);
            app.SNRCheckBox_2.Text = 'SNR';
            app.SNRCheckBox_2.Value = true;
            app.Label_method_2 = uilabel(app.Panel_dataselect);
            app.Label_method_2.Text = 'Methods';
            app.Label_33 = uilabel(app.Panel_dataselect);
            app.Label_33.Text = 'dynamic model';
            app.DropDownorder = uidropdown(app.Panel_dataselect);
            app.DropDownorder.Items = {'1st', '2nd'};
            app.DropDownorder.ValueChangedFcn = createCallbackFcn(app, @DropDownorderValueChanged, true);
            app.DropDownorder.Value = '2nd';
            app.EditFieldorder = uieditfield(app.Panel_dataselect, 'text');
            app.EditFieldorder.Value = 'velocity and acceleration';
            app.Panel_picshow = uipanel(app.GridLayoutWinRH);
            app.Panel_picshow.Title = 'Result_show';
            app.UIAxeswinRH = uiaxes(app.Panel_picshow);
            title(app.UIAxeswinRH, 'moving average window')
            xlabel(app.UIAxeswinRH, 'DAY')
            ylabel(app.UIAxeswinRH, 'RH')
            zlabel(app.UIAxeswinRH, 'Z')
            app.UIAxesoutdectRH = uiaxes(app.Panel_picshow);
            title(app.UIAxesoutdectRH, 'outlier detection')
            xlabel(app.UIAxesoutdectRH, 'DAY')
            ylabel(app.UIAxesoutdectRH, 'RH')
            zlabel(app.UIAxesoutdectRH, 'Z')
            app.Buttonwinrhshow = uibutton(app.Panel_picshow, 'push');
            app.Buttonwinrhshow.ButtonPushedFcn = createCallbackFcn(app, @ButtonwinrhshowPushed, true);
            app.Buttonwinrhshow.Icon = 'GUIicon6.png';
            app.Buttonwinrhshow.Tooltip = {''};
            app.Buttonwinrhshow.Text = 'Show';
            app.Buttonpicsave = uibutton(app.Panel_picshow, 'push');
            app.Buttonpicsave.ButtonPushedFcn = createCallbackFcn(app, @ButtonpicsavePushed, true);
            app.Buttonpicsave.Text = 'Save';
            % Show the figure after all components are created
            app.GNSSRAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GNSSR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GNSSRAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GNSSRAppUIFigure)
        end
    end
end